classdef AngularMomentumApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConservationofAngularMomentumAppUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        Visual                        matlab.ui.control.UIAxes
        RUNButton                     matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        CLEARButton                   matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        ImportDataButton              matlab.ui.control.Button
        WeightsPositionLabel          matlab.ui.control.Label
        ConservationofAngularMomentumLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ProgramSettingsTab            matlab.ui.container.Tab
        COMPortDropDownLabel          matlab.ui.control.Label
        COMPortDropDown               matlab.ui.control.DropDown
        ProgramModeSwitchLabel        matlab.ui.control.Label
        ProgramModeSwitch             matlab.ui.control.Switch
        ScanButton                    matlab.ui.control.Button
        PlotSettingsTab               matlab.ui.container.Tab
        AngularVelocityCheckBox       matlab.ui.control.CheckBox
        MassMomentofInertiaCheckBox   matlab.ui.control.CheckBox
        AngularMomentumCheckBox       matlab.ui.control.CheckBox
        ItemstoPlotLabel              matlab.ui.control.Label
        maximumLabel                  matlab.ui.control.Label
        PositionCheckBox              matlab.ui.control.CheckBox
        KineticEnergyCheckBox         matlab.ui.control.CheckBox
        TimeWindowLabel               matlab.ui.control.Label
        MinimumEditFieldLabel         matlab.ui.control.Label
        MinimumEditField              matlab.ui.control.NumericEditField
        MaximumEditFieldLabel         matlab.ui.control.Label
        MaximumEditField              matlab.ui.control.NumericEditField
        IncludePredictionCheckBox     matlab.ui.control.CheckBox
        UnitSettingsTab               matlab.ui.container.Tab
        AngularVelocityUnitsDropDownLabel  matlab.ui.control.Label
        AngularVelocityUnitsDropDown  matlab.ui.control.DropDown
        PositionUnitsDropDownLabel    matlab.ui.control.Label
        PositionUnitsDropDown         matlab.ui.control.DropDown
        PredictionSettingsTab         matlab.ui.container.Tab
        DryFrictionCheckBox           matlab.ui.control.CheckBox
        RealWorldEffectsLabel         matlab.ui.control.Label
        ViscousFrictionCheckBox       matlab.ui.control.CheckBox
        AirResistanceCheckBox         matlab.ui.control.CheckBox
        NBDonotchangethesewhilethesimulationisrunningLabel_2  matlab.ui.control.Label
    end


    properties (Access = private)
        titles = ["Angular Velocity", "Mass Moment of Inertia", "Position", "Kinetic Energy", "Angular Momentum"]; % titles
        yaxes = [];
        PROGRAM_MODE; % Experimental vs Simulation
        COM_PORT; % COM Port of Device
        RUN_TIME = 3200; % Run Time in Seconds
        PRINT_PERIOD = 200 % ms
        PLOTS = {}; % Array of plots
        LINES = {}; % Array of Lines
        STOP = false; % stop button value
        XLIM = []; % XLimit values
        DATA = []; % Array of data from Arduino
        AngularVelocityUnitsMultiplier = 1;
        PositionUnitsMultiplier = 1;
        THEORY = 0;
        SHAPE1;
        SHAPE2;
        SHAPE3;
        SHAPE4;
    end

    methods (Access = private)
    
        function ManagePlots(app)
            numberChildren = app.AngularVelocityCheckBox.Value + app.MassMomentofInertiaCheckBox.Value + app.PositionCheckBox.Value + app.KineticEnergyCheckBox.Value + app.AngularMomentumCheckBox.Value;
            values = [app.AngularVelocityCheckBox.Value app.MassMomentofInertiaCheckBox.Value app.PositionCheckBox.Value app.KineticEnergyCheckBox.Value app.AngularMomentumCheckBox.Value];
            idx = find(values > 0);
            
            for i = 1:5
                app.Panel.Children(i).Visible = 'off';
                app.Panel.Children(i).Position = [0 0 0 0];
            end
            
            for i = 1:numberChildren
                app.Panel.Children(idx(i)).Position = [11 (10*i+(584/numberChildren)*(i-1)) 537 (584/numberChildren)];
                app.Panel.Children(idx(i)).Visible = 'on';
            end
        end
        
        function ManageChecks(app)
            boxes = [app.AngularVelocityCheckBox app.MassMomentofInertiaCheckBox app.PositionCheckBox app.KineticEnergyCheckBox app.AngularMomentumCheckBox];
            numberChecked = 0;
            for i = 1:length(boxes)
                numberChecked = numberChecked + boxes(i).Value;
            end
            if numberChecked == 3
                for i = 1:length(boxes)
                    if boxes(i).Value == 0
                        boxes(i).Enable = 'off';
                    end
                end
            else
                for i = 1:length(boxes)
                    boxes(i).Enable = 'on';
                end
            end
        end
        
        function UpdateY(app)
            app.yaxes = [app.AngularVelocityUnitsDropDown.Value, "kgm^2", app.PositionUnitsDropDown.Value, "J", "kgm^2/s"];
            for i = 1:5
                ylabel(app.PLOTS{i}, app.yaxes(i));
            end
        end
            
        function timeWindow(app)
            if ~(app.MaximumEditField.Value < app.MinimumEditField.Value)
                app.XLIM = [app.MinimumEditField.Value app.MaximumEditField.Value];
                
                numberChildren = app.AngularVelocityCheckBox.Value + app.MassMomentofInertiaCheckBox.Value + app.PositionCheckBox.Value + app.KineticEnergyCheckBox.Value + app.AngularMomentumCheckBox.Value;
                values = [app.AngularVelocityCheckBox.Value app.MassMomentofInertiaCheckBox.Value app.PositionCheckBox.Value app.KineticEnergyCheckBox.Value app.AngularMomentumCheckBox.Value];
                idx = find(values > 0);
                
                for i = 1:numberChildren
                    app.Panel.Children(idx(i)).XLim = app.XLIM;
                end
            end
        end
        
        function [time, speed, distance, MMI, ke, momentum] = extractSampleData(app, sample)
            % Get received data values
            time = sample(1)/1000;
            speed = sample(2);
            distance = sample(3);
            MMI = sample(4);
            ke = sample(5);
            momentum = sample(6);
        end
        
        function [peakMomentum, momentumEstimate, speedEstimate, kEstimate] = extractEstimates(app, estimates)
            peakMomentum = estimates(1);
            momentumEstimate = estimates(2);
            speedEstimate = estimates(3);
            kEstimate = estimates(4);
        end
        
        function [sample] = processReadings(app, sample)
            [time, speed, distance, ~, ~, ~] = extractSampleData(app, sample);
            
            %Update Position Visual
            shape3X = [(-0.28/0.129)*distance+0.5051162791 (-0.28/0.129)*distance+0.5051162791 (-0.28/0.129)*distance+0.4551162791 (-0.28/0.129)*distance+0.4551162791];
            shape4X = [(0.28/0.129)*distance+0.4948837209 (0.28/0.129)*distance+0.4948837209 (0.28/0.129)*distance+0.5448837209 (0.28/0.129)*distance+0.5448837209];
            app.SHAPE3.XData = shape3X;
            app.SHAPE4.XData = shape4X;
            
            % Mass moment of inertia calculation
            MMI = 6.364e-1*distance^2 + 2.456e-2*distance + 1.247e-2; % Apply fitted polynomial
            
            % Kinetic Energy Calculation
            ke = 0.5*MMI*(speed^2);
            
            % Angular momentum calculation
            momentum = speed*MMI;
            
            sample(4:6) = [MMI, ke, momentum];
            
            % Speed Unit Conversion
            if app.AngularVelocityUnitsDropDown.Value == "RPM"
                speed = speed*60/(2*pi());
            end
            
            % Position unit conversion
            if app.PositionUnitsDropDown.Value == "cm"
                distance = distance * 100;
            elseif app.PositionUnitsDropDown.Value == "mm"
                distance = distance * 1000;
            end
            
            % Live plot data
            addpoints(app.LINES{1}, time, speed);
            addpoints(app.LINES{2}, time, MMI);
            addpoints(app.LINES{3}, time, distance);
            addpoints(app.LINES{4}, time, ke);
            addpoints(app.LINES{5}, time, momentum);
            
            % Find graph xlims
            for i = 1:5
                xlim(app.PLOTS{i}, [0, max(10, time+(time*0.1))]);
            end
            
        end
            
        function [estimates] = processEstimates(app, sample, estimates)
            [time, speed, ~, MMI, ~, momentum] = extractSampleData(app, sample);
            [peakMomentum, momentumEstimate, speedEstimate, ~] = extractEstimates(app, estimates);
            
            % Peak calculation
            if momentum == 0
                peakMomentum = 0;
            elseif momentum > peakMomentum
                peakMomentum = momentum;
                momentumEstimate = peakMomentum;
                speedEstimate = speed;
            end
            
            % Calculate theoretical Friction
            a = 0.00072;
            b = 0.00044;
            friction = 0;
            if app.DryFrictionCheckBox.Value
                friction = friction + a;
            end
            if app.ViscousFrictionCheckBox.Value
                friction = friction + max(0, b*speedEstimate);
            end
            
            % Calculate theoretical Angular Momentum
            momentumEstimate = max(0, (momentumEstimate - friction*(app.PRINT_PERIOD/1000)));
            
            % Calculate theoretical Speed
            speedEstimate = momentumEstimate/MMI;
            
            % Calculate theoretical Energy
            kEstimate = 0.5*MMI*(speedEstimate^2);
            
            % Package estimates as output
            estimates = [peakMomentum, momentumEstimate, speedEstimate, kEstimate];
            
            % Speed Unit Conversion
            if app.AngularVelocityUnitsDropDown.Value == "RPM"
                speedEstimate = speedEstimate*60/(2*pi());
            end
            
            addpoints(app.LINES{6}, time, speedEstimate);
            addpoints(app.LINES{7}, time, kEstimate);
            addpoints(app.LINES{8}, time, momentumEstimate);
        end
        
        
        function recalculateEstimates(app)
            if ~isempty(app.DATA)
                % Clear existing lines
                for i = 6:8
                    clearpoints(app.LINES{i});
                end
                
                % Reinitialise estimates, then iterate through already collected data and calculate points
                estimates = [0, 0, 0, 0];
                for i = 1:length(app.DATA(:, 1))
                    sample = app.DATA(i,:);
                    estimates = processEstimates(app, sample, estimates);
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            res = get(0,'screensize');
            ScreenWidth = res(3);
            ScreenHeight = res(4);
            GuiWidth = 1093;
            GuiHeight = 705;
            
            set(app.ConservationofAngularMomentumAppUIFigure, 'position', [ScreenWidth/2 - GuiWidth/2, ScreenHeight/2 - GuiHeight/2, GuiWidth, GuiHeight]);
            
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            app.COMPortDropDown.Items = seriallist();
            if ~isempty(app.COMPortDropDown.Items)
                app.COMPortDropDown.Value = app.COMPortDropDown.Items(end);
            end
            app.STOPButton.Visible = 'off';
            app.yaxes = [app.AngularVelocityUnitsDropDown.Value, "kgm^2", app.PositionUnitsDropDown.Value, "J", "kgm^2/s"];
                
            for i = 1:5
                app.PLOTS{i} = uiaxes(app.Panel, 'Visible', 'off', 'YAxisLocation', 'left');
                title(app.PLOTS{i}, app.titles(i));
                ylabel(app.PLOTS{i}, app.yaxes(i));
                ytickformat(app.PLOTS{i}, '%,0.3f');
                grid(app.PLOTS{i}, 'on');
                ylim(app.PLOTS{i}, [0 inf]);
                
            end
            app.Panel.Children = flip(app.Panel.Children);
            app.COM_PORT = app.COMPortDropDown.Value;
            app.XLIM = [app.MinimumEditField.Value app.MaximumEditField.Value];
            close;
            
            % Draw Visual
            
            xlim(app.Visual, [0 1]);
            ylim(app.Visual, [0 1]);
            shape2X = [.3 .4 .48 .48 .45 .45 .55 .55 .52 .52 .6 .7]; 
            shape2Y = [.1 .3 .30 .32 .32 .45 .45 .32 .32 .30 .3 .1];
            shape1X = [.1 .9 .9 .1];
            shape1Y = [.35 .35 .42 .42];
            shape3X = [.44 .44 .39 .39];
            shape3Y = [.32 .45 .45 .32];
            shape4X = [.56 .56 .61 .61];
            shape4Y = [.32 .45 .45 .32];
            app.SHAPE1 = patch(app.Visual, shape1X, shape1Y, [.8 .8 .8]);
            app.SHAPE2 = patch(app.Visual, shape2X, shape2Y, [.5 .5 .5]);
            app.SHAPE3 = patch(app.Visual, shape3X, shape3Y, 'y');
            app.SHAPE4 = patch(app.Visual, shape4X, shape4Y, 'y');
            axis(app.Visual, 'off'); 
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Value changed function: MassMomentofInertiaCheckBox
        function MassMomentofInertiaCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Value changed function: AngularMomentumCheckBox
        function AngularMomentumCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            if app.STOP
                return
            end
                      
            try
                fclose(instrfind());
            catch
            end
            
            % Toggle Buttons
            app.RUNButton.Visible = 'off';
            app.STOPButton.Visible = 'on';
            
            % Clear Graphs of Data
            for i = 1:5
                cla(app.Panel.Children(i));
            end
            
            % Disable Time Window
            set(app.MinimumEditField, 'Enable', 'off');
            set(app.MaximumEditField, 'Enable', 'off');
            
            % Serial setup
            if app.PROGRAM_MODE == "Experimental"
                s1 = serial(app.COM_PORT); % Serial port
                s1.BaudRate=115200;               % define baud rate
                set(s1, 'terminator', 'LF');    % define the terminator for println
                fopen(s1);
                timeLength = app.RUN_TIME*1000/app.PRINT_PERIOD;
            else
                % Simulation mode setup
                if isempty(app.DATA)
                    f = warndlg('No Data has been imported or recorded!','Check Yourself!');
                    app.STOP = true;
                    timeLength = 0;
                else
                    timeLength = length(app.DATA(:,1));
                end
            end
            
            % Generate plots
            for i = 1:5
                app.LINES{i} = animatedline(app.Panel.Children(i), 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            end
            % Generate prediction plots
            app.LINES{6} = animatedline(app.Panel.Children(1), 'Color', 'r', 'LineWidth', 2);
            app.LINES{7} = animatedline(app.Panel.Children(4), 'Color', 'r', 'LineWidth', 2);
            app.LINES{8} = animatedline(app.Panel.Children(5), 'Color', 'r', 'LineWidth', 2);
            
            % Check whether to include predicitons
            IncludePredictionCheckBoxValueChanged(app, event);

            %% Serial comms
            try                             % use try catch to ensure fclose
            
            % Initialise variables
            timeIndex=0;
            estimates = [0, 0, 0, 0];
            simRow = 1;
            
            while (timeIndex < timeLength)
                % End the run if the stop button has been pressed
                if app.STOP
                    break
                end
                timeIndex = timeIndex+1;
                
                % Get a new sample based on mode
                if app.PROGRAM_MODE == "Experimental"
                    % Read serial, show warning if no data available
                    try 
                    sample = fscanf(s1,'%s');
                    sample = textscan(sample, '%f%f%f', 'Delimiter', ',');
                    catch
                        f = warndlg('Unable to commmunicate with device. Check COM Port is correct.','Communication Error');
                        break;
                    end
                        
                    sample = cell2mat(sample);
                    sample = [sample, 0, 0, 0]; % Initialise calculated values to 0
                else
                    % Use simulated data
                    sample = app.DATA(simRow,:);
                    simRow = simRow + 1;
                end
                
                % Process readings from arduino
                sample = processReadings(app, sample);
                
                % Process theory predictions of derived values and get feedback
                estimates = processEstimates(app, sample, estimates);
                         
                drawnow limitrate;
                
                % Clear input to reduce lag
                if mod(timeIndex, 5) == 0 && app.PROGRAM_MODE == "Experimental"
                    flushinput(s1);
                end
                
                if app.PROGRAM_MODE == "Experimental"
                    app.DATA = [app.DATA; sample]; % Add a new row to data table
                end
            end
            
            app.STOP = false;
            
            % Toggle Buttons
            app.RUNButton.Visible = 'on';
            app.STOPButton.Visible = 'off';
            
            % Disable Time Window
            set(app.MinimumEditField, 'Enable', 'on');
            set(app.MaximumEditField, 'Enable', 'on');
            
            if app.PROGRAM_MODE == "Experimental"
                fclose(s1);
            end
            catch me
                if app.PROGRAM_MODE == "Experimental" 
                    fclose(s1); % always, always want to close s1
                end             
                rethrow(me);
            end
        end

        % Callback function
        function RunTimesecondsEditFieldValueChanged(app, event)
            app.RUN_TIME = app.RunTimesecondsEditField.Value;
            app.MaximumEditField.Value = app.RunTimesecondsEditField.Value;
            ManagePlots(app);

        end

        % Callback function
        function ProgramModeSwitchValueChanged(app, event)
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOP = true;
            RUNButtonPushed(app, event);
        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            app.COM_PORT = app.COMPortDropDown.Value;
            
        end

        % Value changed function: MaximumEditField
        function MaximumEditFieldValueChanged(app, event)
            timeWindow(app);
            
        end

        % Value changed function: MinimumEditField
        function MinimumEditFieldValueChanged(app, event)
            timeWindow(app);
            
        end

        % Value changed function: KineticEnergyCheckBox
        function KineticEnergyCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
            
        end

        % Value changed function: PositionCheckBox
        function PositionCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
            
        end

        % Value changed function: AngularVelocityUnitsDropDown
        function AngularVelocityUnitsDropDownValueChanged(app, event)
            % Update y-axis label
            UpdateY(app);
            
           % Get the new units multiplier
            if app.AngularVelocityUnitsDropDown.Value == "RPM"
                newMultiplier = 60/(2*pi);
            else
                newMultiplier = 1;
            end
            
            % Convert units of past data points
            if ~isempty(app.DATA)
                [time, speed] = getpoints(app.LINES{1}); % Retrieve old points
                [~, speedEstimate] = getpoints(app.LINES{6}); % Retrieve old points
                clearpoints(app.LINES{1}); % Clear old points
                clearpoints(app.LINES{6}); % Clear old points
                addpoints(app.LINES{1}, time, speed*newMultiplier/app.AngularVelocityUnitsMultiplier); % Multiply old points to convert units
                addpoints(app.LINES{6}, time, speedEstimate*newMultiplier/app.AngularVelocityUnitsMultiplier); % Multiply old points to convert units
            end
            % Update the units multiplier
            app.AngularVelocityUnitsMultiplier = newMultiplier;
        end

        % Value changed function: PositionUnitsDropDown
        function PositionUnitsDropDownValueChanged(app, event)

            % Update y-axis label
            UpdateY(app);
            
            % Get the new units multiplier
            if app.PositionUnitsDropDown.Value == "cm"
                newMultiplier = 100;
            elseif app.PositionUnitsDropDown.Value == "mm"
                newMultiplier = 1000;
            else
                newMultiplier = 1;
            end
            
            % Convert units of past data points
            if ~isempty(app.DATA)
                [time, position] = getpoints(app.LINES{3}); % Retrieve old points
                clearpoints(app.LINES{3}); % Clear old points
                addpoints(app.LINES{3}, time, position*newMultiplier/app.PositionUnitsMultiplier); % Multiply old points to convert units
            end
            % Update the units multiplier
            app.PositionUnitsMultiplier = newMultiplier;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            for i = 1:5
                cla(app.Panel.Children(i));
            end
            app.DATA = [];
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Get the name of the file that the user wants to save.
            % Note, if you're saving an image you can use imsave() instead of uiputfile().
            [baseFileName, folder] = uiputfile({'*.mat', 'MAT-File (*.mat)'; '*.csv', 'Comma-separated values (*.csv)'; '*.xlsx', 'Excel Spreadsheet (*.xlsx)';}, 'Save Experiment Data As..', 'data.mat');
            if baseFileName == 0
              % User clicked the Cancel button.
              return;
            end
            data = app.DATA;
            fullFileName = fullfile(folder, baseFileName);
            
            % Get the file extension
            [~,~,ext] = fileparts(fullFileName);
            % Use appropriate method to write output file
            if ext == ".mat"
                save(fullFileName, 'data');
            elseif ext == ".csv"
                csvwrite(fullFileName, data);
            elseif ext == ".xlsx"
                header = ["Time", "Angular Velocity", "Position", "Mass Moment of Inertia", "Kinetic Energy", "Angular Momentum"];
                tempData = [header; num2cell(data)];
                xlswrite(fullFileName, tempData);
            else
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [fileName, path] = uigetfile({'*.mat;*.csv', 'Accepted Filetypes (*.mat, *.csv)';});
            fullFileName = fullfile(path, fileName);
            [~,~,ext] = fileparts(fullFileName);
            if ext == ".mat"
                load(fullFileName);
                app.DATA = data;
            elseif ext == ".csv"
                app.DATA = csvread(fullFileName);
            end    
        end

        % Value changed function: ProgramModeSwitch
        function ProgramModeSwitchValueChanged2(app, event)
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            
        end

        % Value changed function: IncludePredictionCheckBox
        function IncludePredictionCheckBoxValueChanged(app, event)
            for i = 6:8
                if app.IncludePredictionCheckBox.Value
                    app.LINES{i}.Visible = 'on';
                else
                    app.LINES{i}.Visible = 'off';
                end
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.COMPortDropDown.Items = seriallist();
        end

        % Value changed function: DryFrictionCheckBox
        function DryFrictionCheckBoxValueChanged(app, event)
            recalculateEstimates(app);
        end

        % Value changed function: ViscousFrictionCheckBox
        function ViscousFrictionCheckBoxValueChanged(app, event)
            recalculateEstimates(app);
        end

        % Value changed function: AirResistanceCheckBox
        function AirResistanceCheckBoxValueChanged(app, event)
            recalculateEstimates(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConservationofAngularMomentumAppUIFigure = uifigure;
            app.ConservationofAngularMomentumAppUIFigure.Name = 'Conservation of Angular Momentum App';
            app.Panel = uipanel(app.ConservationofAngularMomentumAppUIFigure);
            app.Visual = uiaxes(app.ConservationofAngularMomentumAppUIFigure);
            xlabel(app.Visual, 'X')
            ylabel(app.Visual, 'Y')
            app.RUNButton = uibutton(app.ConservationofAngularMomentumAppUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.ConservationofAngularMomentumAppUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CLEARButton = uibutton(app.ConservationofAngularMomentumAppUIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ExportDataButton = uibutton(app.ConservationofAngularMomentumAppUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ImportDataButton = uibutton(app.ConservationofAngularMomentumAppUIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.WeightsPositionLabel = uilabel(app.ConservationofAngularMomentumAppUIFigure);
            app.WeightsPositionLabel.Text = 'Weights Position';
            app.ConservationofAngularMomentumLabel = uilabel(app.ConservationofAngularMomentumAppUIFigure);
            app.ConservationofAngularMomentumLabel.Text = 'Conservation of Angular Momentum';
            app.TabGroup = uitabgroup(app.ConservationofAngularMomentumAppUIFigure);
            app.ProgramSettingsTab = uitab(app.TabGroup);
            app.ProgramSettingsTab.Title = 'Program Settings';
            app.COMPortDropDownLabel = uilabel(app.ProgramSettingsTab);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ProgramSettingsTab);
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.ProgramModeSwitchLabel = uilabel(app.ProgramSettingsTab);
            app.ProgramModeSwitchLabel.Text = 'Program Mode';
            app.ProgramModeSwitch = uiswitch(app.ProgramSettingsTab, 'slider');
            app.ProgramModeSwitch.Items = {'Experimental', 'Simulation'};
            app.ProgramModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ProgramModeSwitchValueChanged2, true);
            app.ProgramModeSwitch.Value = 'Experimental';
            app.ScanButton = uibutton(app.ProgramSettingsTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.PlotSettingsTab = uitab(app.TabGroup);
            app.PlotSettingsTab.Title = 'Plot Settings';
            app.AngularVelocityCheckBox = uicheckbox(app.PlotSettingsTab);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.MassMomentofInertiaCheckBox = uicheckbox(app.PlotSettingsTab);
            app.MassMomentofInertiaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MassMomentofInertiaCheckBoxValueChanged, true);
            app.MassMomentofInertiaCheckBox.Text = 'Mass Moment of Inertia';
            app.AngularMomentumCheckBox = uicheckbox(app.PlotSettingsTab);
            app.AngularMomentumCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularMomentumCheckBoxValueChanged, true);
            app.AngularMomentumCheckBox.Text = 'Angular Momentum';
            app.ItemstoPlotLabel = uilabel(app.PlotSettingsTab);
            app.ItemstoPlotLabel.Text = 'Items to Plot';
            app.maximumLabel = uilabel(app.PlotSettingsTab);
            app.maximumLabel.Text = '(3 maximum)';
            app.PositionCheckBox = uicheckbox(app.PlotSettingsTab);
            app.PositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PositionCheckBoxValueChanged, true);
            app.PositionCheckBox.Text = 'Position';
            app.KineticEnergyCheckBox = uicheckbox(app.PlotSettingsTab);
            app.KineticEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @KineticEnergyCheckBoxValueChanged, true);
            app.KineticEnergyCheckBox.Text = 'Kinetic Energy';
            app.TimeWindowLabel = uilabel(app.PlotSettingsTab);
            app.TimeWindowLabel.Text = 'Time Window';
            app.MinimumEditFieldLabel = uilabel(app.PlotSettingsTab);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.PlotSettingsTab, 'numeric');
            app.MinimumEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumEditFieldValueChanged, true);
            app.MaximumEditFieldLabel = uilabel(app.PlotSettingsTab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.PlotSettingsTab, 'numeric');
            app.MaximumEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumEditFieldValueChanged, true);
            app.IncludePredictionCheckBox = uicheckbox(app.PlotSettingsTab);
            app.IncludePredictionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludePredictionCheckBoxValueChanged, true);
            app.IncludePredictionCheckBox.Text = 'Include Prediction';
            app.IncludePredictionCheckBox.Value = true;
            app.UnitSettingsTab = uitab(app.TabGroup);
            app.UnitSettingsTab.Title = 'Unit Settings';
            app.AngularVelocityUnitsDropDownLabel = uilabel(app.UnitSettingsTab);
            app.AngularVelocityUnitsDropDownLabel.Text = 'Angular Velocity Units';
            app.AngularVelocityUnitsDropDown = uidropdown(app.UnitSettingsTab);
            app.AngularVelocityUnitsDropDown.Items = {'Rad/s', 'RPM'};
            app.AngularVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityUnitsDropDownValueChanged, true);
            app.AngularVelocityUnitsDropDown.Value = 'Rad/s';
            app.PositionUnitsDropDownLabel = uilabel(app.UnitSettingsTab);
            app.PositionUnitsDropDownLabel.Text = 'Position Units';
            app.PositionUnitsDropDown = uidropdown(app.UnitSettingsTab);
            app.PositionUnitsDropDown.Items = {'m', 'cm', 'mm'};
            app.PositionUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionUnitsDropDownValueChanged, true);
            app.PositionUnitsDropDown.Value = 'm';
            app.PredictionSettingsTab = uitab(app.TabGroup);
            app.PredictionSettingsTab.Title = 'Prediction Settings';
            app.DryFrictionCheckBox = uicheckbox(app.PredictionSettingsTab);
            app.DryFrictionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DryFrictionCheckBoxValueChanged, true);
            app.DryFrictionCheckBox.Text = 'Dry Friction';
            app.RealWorldEffectsLabel = uilabel(app.PredictionSettingsTab);
            app.RealWorldEffectsLabel.Text = 'Real World Effects';
            app.ViscousFrictionCheckBox = uicheckbox(app.PredictionSettingsTab);
            app.ViscousFrictionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViscousFrictionCheckBoxValueChanged, true);
            app.ViscousFrictionCheckBox.Text = 'Viscous Friction';
            app.AirResistanceCheckBox = uicheckbox(app.PredictionSettingsTab);
            app.AirResistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @AirResistanceCheckBoxValueChanged, true);
            app.AirResistanceCheckBox.Enable = 'off';
            app.AirResistanceCheckBox.Text = 'Air Resistance';
            app.NBDonotchangethesewhilethesimulationisrunningLabel_2 = uilabel(app.PredictionSettingsTab);
            app.NBDonotchangethesewhilethesimulationisrunningLabel_2.Text = 'N.B. Do not change these while the simulation is running';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AngularMomentumApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConservationofAngularMomentumAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConservationofAngularMomentumAppUIFigure)
        end
    end
end