classdef AngularMomentumApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConservationofAngularMomentumLabel  matlab.ui.control.Label
        AngularVelocityCheckBox       matlab.ui.control.CheckBox
        MassMomentofInertiaCheckBox   matlab.ui.control.CheckBox
        AngularMomentumCheckBox       matlab.ui.control.CheckBox
        ItemstoPlotLabel              matlab.ui.control.Label
        maximumLabel                  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        RUNButton                     matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        MinimumEditFieldLabel         matlab.ui.control.Label
        MinimumEditField              matlab.ui.control.NumericEditField
        MaximumEditFieldLabel         matlab.ui.control.Label
        MaximumEditField              matlab.ui.control.NumericEditField
        TimeWindowLabel               matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ProgramSettingsTab            matlab.ui.container.Tab
        COMPortDropDownLabel          matlab.ui.control.Label
        COMPortDropDown               matlab.ui.control.DropDown
        ProgramModeSwitchLabel        matlab.ui.control.Label
        ProgramModeSwitch             matlab.ui.control.Switch
        ScanButton                    matlab.ui.control.Button
        ModelairresistanceCheckBox    matlab.ui.control.CheckBox
        RuntimeSettingsTab            matlab.ui.container.Tab
        AngularVelocityUnitsDropDownLabel  matlab.ui.control.Label
        AngularVelocityUnitsDropDown  matlab.ui.control.DropDown
        PositionUnitsDropDownLabel    matlab.ui.control.Label
        PositionUnitsDropDown         matlab.ui.control.DropDown
        NBDonotchangethesewhilethesimulationisrunningLabel  matlab.ui.control.Label
        GridlinesLabel                matlab.ui.control.Label
        PositionCheckBox              matlab.ui.control.CheckBox
        KineticEnergyCheckBox         matlab.ui.control.CheckBox
        CLEARButton                   matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        ImportDataButton              matlab.ui.control.Button
        IncludeTheoryCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = private)
        titles = ["Angular Velocity", "Mass Moment of Inertia", "Position", "Kinetic Energy", "Angular Momentum"]; % titles
        yaxes = [];
        PROGRAM_MODE; % Experimental vs Simulation
        COM_PORT; % COM Port of Device
        RUN_TIME = 3200; % Run Time in Seconds
        PRINT_PERIOD = 200 % ms
        PLOTS = []; % Array of plots
        STOP = false; % stop button value
        XLIM = []; % XLimit values
        DATA = []; % Array of data from Arduino
        THEORY = 0;
    end

    methods (Access = private)
    
        function ManagePlots(app)
            numberChildren = app.AngularVelocityCheckBox.Value + app.MassMomentofInertiaCheckBox.Value + app.PositionCheckBox.Value + app.KineticEnergyCheckBox.Value + app.AngularMomentumCheckBox.Value;
            values = [app.AngularVelocityCheckBox.Value app.MassMomentofInertiaCheckBox.Value app.PositionCheckBox.Value app.KineticEnergyCheckBox.Value app.AngularMomentumCheckBox.Value];
            idx = find(values > 0);
            
            for i = 1:5
                app.Panel.Children(i).Visible = 'off';
                app.Panel.Children(i).Position = [0 0 0 0];
            end
            
            for i = 1:numberChildren
                app.Panel.Children(idx(i)).Position = [11 (10*i+(584/numberChildren)*(i-1)) 537 (584/numberChildren)];
                app.Panel.Children(idx(i)).Visible = 'on';
            end
        end
        
        function ManageChecks(app)
            boxes = [app.AngularVelocityCheckBox app.MassMomentofInertiaCheckBox app.PositionCheckBox app.KineticEnergyCheckBox app.AngularMomentumCheckBox];
            numberChecked = 0;
            for i = 1:length(boxes)
                numberChecked = numberChecked + boxes(i).Value;
            end
            if numberChecked == 3
                for i = 1:length(boxes)
                    if boxes(i).Value == 0
                        boxes(i).Enable = 'off';
                    end
                end
            else
                for i = 1:length(boxes)
                    boxes(i).Enable = 'on';
                end
            end
        end
        
        function UpdateY(app)
            app.yaxes = [app.AngularVelocityUnitsDropDown.Value, "kgm^2", app.PositionUnitsDropDown.Value, "J", "kgm^2/s"];
            for i = 1:5
                ylabel(app.PLOTS(i), app.yaxes(i));
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            app.COMPortDropDown.Items = seriallist();
            if ~isempty(app.COMPortDropDown.Items)
                app.COMPortDropDown.Value = app.COMPortDropDown.Items(end);
            end
            app.MaximumEditField.Value = 90;
            app.STOPButton.Visible = 'off';
            app.yaxes = [app.AngularVelocityUnitsDropDown.Value, "kgm^2", app.PositionUnitsDropDown.Value, "J", "kgm^2/s"];
                
            for i = 1:5
                app.PLOTS(i) = uiaxes(app.Panel, 'Visible', 'off', 'YAxisLocation', 'right');
                title(app.PLOTS(i), app.titles(i));
                ylabel(app.PLOTS(i), app.yaxes(i));
                grid(app.PLOTS(i), 'on');                
                
            end
            app.Panel.Children = flip(app.Panel.Children);
            app.COM_PORT = app.COMPortDropDown.Value;
            app.XLIM = [app.MinimumEditField.Value app.MaximumEditField.Value];
            close;
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Value changed function: MassMomentofInertiaCheckBox
        function MassMomentofInertiaCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Value changed function: AngularMomentumCheckBox
        function AngularMomentumCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                fclose(instrfind());
            catch
            end
            app.RUNButton.Visible = 'off';
            app.STOPButton.Visible = 'on';
            for i = 1:5
                cla(app.Panel.Children(i));
            end
            
            % Serial setup
            if app.PROGRAM_MODE == "Experimental"
                s1 = serial(app.COM_PORT); % Serial port
                s1.BaudRate=115200;               % define baud rate
                set(s1, 'terminator', 'LF');    % define the terminator for println
                fopen(s1);
                timeLength = app.RUN_TIME*1000/app.PRINT_PERIOD;
            else
                % Simulation mode setup
                if isempty(app.DATA)
                    f = warndlg('No Data has been imported or recorded!','Check Yourself!');
                    app.STOP = true;
                    timeLength = 0;
                else
                    timeLength = length(app.DATA(:,1));
                end
            end
            
            % Generate plots
            for i = 1:5
                plotlines(i) = animatedline(app.Panel.Children(i), 'Color', [0 0.4470 0.7410], 'LineWidth', 2);
            end
            % Generate prediction plots
            plotlines(6) = animatedline(app.Panel.Children(1), 'Color', 'r', 'LineWidth', 2);
            plotlines(7) = animatedline(app.Panel.Children(4), 'Color', 'r', 'LineWidth', 2);
            plotlines(8) = animatedline(app.Panel.Children(5), 'Color', 'r', 'LineWidth', 2);
            
            %% Serial comms
            try                             % use try catch to ensure fclose
            
            % Initialise variables
            timeIndex=0;
            peakMomentum=0;
            speedEstimate = 0;
            momentumEstimate = 0;
            simRow = 1;
            
            while (timeIndex < timeLength)
                % End the run if the stop button has been pressed
                if app.STOP
                    break
                end
                timeIndex = timeIndex+1;
                
                % Get a new sample based on mode
                if app.PROGRAM_MODE == "Experimental"
                    % Read serial, show warning if no data available
                    try 
                    sample = fscanf(s1,'%s');
                    sample = textscan(sample, '%f%f%f', 'Delimiter', ',');
                    catch
                        f = warndlg('Unable to commmunicate with device. Check COM Port is correct.','Communication Error');
                        break;
                    end
                        
                    sample = cell2mat(sample);
                    sample = [sample, 0, 0, 0]; % Initialise calculated values to 0
                else
                    % Use simulated data
                    sample = app.DATA(simRow,:);
                    simRow = simRow + 1;
                end
                
                % Add a new row to data table
                app.DATA = [app.DATA; sample]; 
                
                % Get received data values
                time = app.DATA(end,1)/1000;
                speed = app.DATA(end,2);
                distance = app.DATA(end,3);
                
                % Mass moment of inertia calculation
                MMI = 6.364e-1*distance^2 + 2.456e-2*distance + 1.247e-2; % Apply fitted polynomial
                app.DATA(end,4) = MMI;
                
                % Kinetic Energy Calculation
                ke = 0.5*MMI*(speed^2);
                app.DATA(end, 5) = ke;
                
                % Angular momentum calculation
                momentum = speed*MMI;
                app.DATA(end,6) = momentum; % Time slice
                
                % Peak calculation
                if momentum == 0
                    peakMomentum = 0;
                elseif momentum > peakMomentum
                    peakMomentum = momentum;
                    momentumEstimate = peakMomentum;
                    speedEstimate = speed;
                end
                
                % Calculate theoretical Angular Momentum
                a = 0.00072;
                b = 0.00044;
                friction = a;
                if app.ModelairresistanceCheckBox.Value
                    friction = friction + max(0, b*speedEstimate);
                end
                momentumEstimate = max(0, (momentumEstimate - friction*(app.PRINT_PERIOD/1000)));
                
                % Calculate theoretical Speed
                speedEstimate = momentumEstimate/MMI;
                
                % Calculate theoretical Energy
                kEstimate = 0.5*MMI*(speedEstimate^2);
                
                % Position unit conversion
                if app.PositionUnitsDropDown.Value == "cm"
                    distance = distance * 100;
                elseif app.PositionUnitsDropDown.Value == "mm"
                    distance = distance * 1000;
                end
                
                % Speed Unit Conversion
                if app.AngularVelocityUnitsDropDown.Value == "RPM"
                    speed = speed*60/(2*pi());
                end
                
                % Live plot data
                addpoints(plotlines(1), time, speed);
                addpoints(plotlines(2), time, MMI);
                addpoints(plotlines(3), time, distance);
                addpoints(plotlines(4), time, ke);
                addpoints(plotlines(5), time, momentum);
                addpoints(plotlines(6), time, speedEstimate);
                addpoints(plotlines(7), time, kEstimate);
                addpoints(plotlines(8), time, momentumEstimate);
                
                % Control visibility of theory plots
                % NOTE: Can crash if clear button pressed
                for i = 6:8
                    if app.THEORY
                        plotlines(i).Visible = 'on';
                    else
                        plotlines(i).Visible = 'off';
                    end
                end
            
                drawnow;
            end
            
            app.STOP = false;
            app.RUNButton.Visible = 'on';
            app.STOPButton.Visible = 'off';
            
            if app.PROGRAM_MODE == "Experimental"
                fclose(s1);
            end
            catch me
                if app.PROGRAM_MODE == "Experimental" 
                    fclose(s1); % always, always want to close s1
                end             
                rethrow(me);
            end
        end

        % Callback function
        function RunTimesecondsEditFieldValueChanged(app, event)
            app.RUN_TIME = app.RunTimesecondsEditField.Value;
            app.MaximumEditField.Value = app.RunTimesecondsEditField.Value;
            ManagePlots(app);

        end

        % Callback function
        function ProgramModeSwitchValueChanged(app, event)
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOP = true;
        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            app.COM_PORT = app.COMPortDropDown.Value;
            
        end

        % Value changed function: MaximumEditField
        function MaximumEditFieldValueChanged(app, event)
            app.XLIM = [app.MinimumEditField.Value app.MaximumEditField.Value];
            
            numberChildren = app.AngularVelocityCheckBox.Value + app.MassMomentofInertiaCheckBox.Value + app.AngularMomentumCheckBox.Value;
            values = [app.AngularVelocityCheckBox.Value app.MassMomentofInertiaCheckBox.Value app.AngularMomentumCheckBox.Value];
            idx = find(values > 0);
            
            for i = 1:numberChildren
                app.Panel.Children(idx(i)).XLim = app.XLIM;
            end
            
        end

        % Value changed function: MinimumEditField
        function MinimumEditFieldValueChanged(app, event)
            app.XLIM = [app.MinimumEditField.Value app.MaximumEditField.Value];
            
            numberChildren = app.AngularVelocityCheckBox.Value + app.MassMomentofInertiaCheckBox.Value + app.AngularMomentumCheckBox.Value;
            values = [app.AngularVelocityCheckBox.Value app.MassMomentofInertiaCheckBox.Value app.AngularMomentumCheckBox.Value];
            idx = find(values > 0);
            
            for i = 1:numberChildren
                app.Panel.Children(idx(i)).XLim = app.XLIM;
            end
            
        end

        % Value changed function: KineticEnergyCheckBox
        function KineticEnergyCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
            
        end

        % Value changed function: PositionCheckBox
        function PositionCheckBoxValueChanged(app, event)
            ManagePlots(app);
            ManageChecks(app);
            
        end

        % Value changed function: AngularVelocityUnitsDropDown
        function AngularVelocityUnitsDropDownValueChanged(app, event)
            UpdateY(app);            
        end

        % Value changed function: PositionUnitsDropDown
        function PositionUnitsDropDownValueChanged(app, event)
            UpdateY(app);            
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            for i = 1:5
                cla(app.Panel.Children(i));
            end
            app.DATA = [];
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Get the name of the file that the user wants to save.
            % Note, if you're saving an image you can use imsave() instead of uiputfile().
            [baseFileName, folder] = uiputfile({'*.mat', 'MAT-File (*.mat)'; '*.csv', 'Comma-separated values (*.csv)'; '*.xlsx', 'Excel Spreadsheet (*.xlsx)';}, 'Save Experiment Data As..', 'data.mat');
            if baseFileName == 0
              % User clicked the Cancel button.
              return;
            end
            data = app.DATA;
            fullFileName = fullfile(folder, baseFileName);
            
            % Get the file extension
            [~,~,ext] = fileparts(fullFileName);
            % Use appropriate method to write output file
            if ext == ".mat"
                save(fullFileName, 'data');
            elseif ext == ".csv"
                csvwrite(fullFileName, data);
            elseif ext == ".xlsx"
                header = ["Time", "Angular Velocity", "Position", "Mass Moment of Inertia", "Kinetic Energy", "Angular Momentum"];
                tempData = [header; num2cell(data)];
                xlswrite(fullFileName, tempData);
            else
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [fileName, path] = uigetfile({'*.mat;*.csv', 'Accepted Filetypes (*.mat, *.csv)';});
            fullFileName = fullfile(path, fileName);
            [~,~,ext] = fileparts(fullFileName);
            if ext == ".mat"
                load(fullFileName);
                app.DATA = data;
            elseif ext == ".csv"
                app.DATA = csvread(fullFileName);
            end    
        end

        % Value changed function: ProgramModeSwitch
        function ProgramModeSwitchValueChanged2(app, event)
            app.PROGRAM_MODE = app.ProgramModeSwitch.Value;
            
        end

        % Value changed function: IncludeTheoryCheckBox
        function IncludeTheoryCheckBoxValueChanged(app, event)
            app.THEORY = app.IncludeTheoryCheckBox.Value;
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.COMPortDropDown.Items = seriallist();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConservationofAngularMomentumLabel = uilabel(app.UIFigure);
            app.ConservationofAngularMomentumLabel.Text = 'Conservation of Angular Momentum';
            app.AngularVelocityCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.MassMomentofInertiaCheckBox = uicheckbox(app.UIFigure);
            app.MassMomentofInertiaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MassMomentofInertiaCheckBoxValueChanged, true);
            app.MassMomentofInertiaCheckBox.Text = 'Mass Moment of Inertia';
            app.AngularMomentumCheckBox = uicheckbox(app.UIFigure);
            app.AngularMomentumCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularMomentumCheckBoxValueChanged, true);
            app.AngularMomentumCheckBox.Text = 'Angular Momentum';
            app.ItemstoPlotLabel = uilabel(app.UIFigure);
            app.ItemstoPlotLabel.Text = 'Items to Plot';
            app.maximumLabel = uilabel(app.UIFigure);
            app.maximumLabel.Text = '(3 maximum)';
            app.Panel = uipanel(app.UIFigure);
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MinimumEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumEditFieldValueChanged, true);
            app.MaximumEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumEditFieldValueChanged, true);
            app.TimeWindowLabel = uilabel(app.UIFigure);
            app.TimeWindowLabel.Text = 'Time Window';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProgramSettingsTab = uitab(app.TabGroup);
            app.ProgramSettingsTab.Title = 'Program Settings';
            app.COMPortDropDownLabel = uilabel(app.ProgramSettingsTab);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ProgramSettingsTab);
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.ProgramModeSwitchLabel = uilabel(app.ProgramSettingsTab);
            app.ProgramModeSwitchLabel.Text = 'Program Mode';
            app.ProgramModeSwitch = uiswitch(app.ProgramSettingsTab, 'slider');
            app.ProgramModeSwitch.Items = {'Experimental', 'Simulation'};
            app.ProgramModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ProgramModeSwitchValueChanged2, true);
            app.ProgramModeSwitch.Value = 'Experimental';
            app.ScanButton = uibutton(app.ProgramSettingsTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ModelairresistanceCheckBox = uicheckbox(app.ProgramSettingsTab);
            app.ModelairresistanceCheckBox.Text = 'Model air resistance';
            app.RuntimeSettingsTab = uitab(app.TabGroup);
            app.RuntimeSettingsTab.Title = 'Runtime Settings';
            app.AngularVelocityUnitsDropDownLabel = uilabel(app.RuntimeSettingsTab);
            app.AngularVelocityUnitsDropDownLabel.Text = 'Angular Velocity Units';
            app.AngularVelocityUnitsDropDown = uidropdown(app.RuntimeSettingsTab);
            app.AngularVelocityUnitsDropDown.Items = {'Rad/s', 'RPM'};
            app.AngularVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityUnitsDropDownValueChanged, true);
            app.AngularVelocityUnitsDropDown.Value = 'Rad/s';
            app.PositionUnitsDropDownLabel = uilabel(app.RuntimeSettingsTab);
            app.PositionUnitsDropDownLabel.Text = 'Position Units';
            app.PositionUnitsDropDown = uidropdown(app.RuntimeSettingsTab);
            app.PositionUnitsDropDown.Items = {'m', 'cm', 'mm'};
            app.PositionUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionUnitsDropDownValueChanged, true);
            app.PositionUnitsDropDown.Value = 'm';
            app.NBDonotchangethesewhilethesimulationisrunningLabel = uilabel(app.RuntimeSettingsTab);
            app.NBDonotchangethesewhilethesimulationisrunningLabel.Text = 'N.B. Do not change these while the simulation is running';
            app.GridlinesLabel = uilabel(app.UIFigure);
            app.GridlinesLabel.Text = {'modern colors'; 'Visual weight Diagram'; ''; 'Show Theory'};
            app.PositionCheckBox = uicheckbox(app.UIFigure);
            app.PositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PositionCheckBoxValueChanged, true);
            app.PositionCheckBox.Text = 'Position';
            app.KineticEnergyCheckBox = uicheckbox(app.UIFigure);
            app.KineticEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @KineticEnergyCheckBoxValueChanged, true);
            app.KineticEnergyCheckBox.Text = 'Kinetic Energy';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.IncludeTheoryCheckBox = uicheckbox(app.UIFigure);
            app.IncludeTheoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeTheoryCheckBoxValueChanged, true);
            app.IncludeTheoryCheckBox.Text = 'Include Theory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AngularMomentumApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end